classdef Analyse_CalciumScan_Transitoire < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AnalyseImageScanTab             matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        IntervalleTempsminEditField     matlab.ui.control.NumericEditField
        IntervalleTempsminEditFieldLabel  matlab.ui.control.Label
        SeuilAmplitudemaxEditField      matlab.ui.control.NumericEditField
        SeuilAmplitudemaxEditFieldLabel  matlab.ui.control.Label
        dtEditField                     matlab.ui.control.NumericEditField
        dtEditFieldLabel                matlab.ui.control.Label
        postTransitoireEditField        matlab.ui.control.NumericEditField
        postTransitoireEditFieldLabel   matlab.ui.control.Label
        preTransitoireEditField         matlab.ui.control.NumericEditField
        preTransitoireEditFieldLabel    matlab.ui.control.Label
        detrendCheckBox                 matlab.ui.control.CheckBox
        CalculetSauvegardeButton        matlab.ui.control.Button
        ChargeScanButton                matlab.ui.control.Button
        NomFichierEditField             matlab.ui.control.EditField
        NomFichierEditFieldLabel        matlab.ui.control.Label
        CheminEditField                 matlab.ui.control.EditField
        CheminEditFieldLabel            matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        AnalysefichierscanTab           matlab.ui.container.Tab
        TempsTotalEnregistrementsecEditField  matlab.ui.control.NumericEditField
        TempsTotalEnregistrementsecEditFieldLabel  matlab.ui.control.Label
        DeterminationdelaFluoMinimaleBaseLineButton  matlab.ui.control.Button
        reconstructiondelimageCheckBox  matlab.ui.control.CheckBox
        facteurdelissageDfofEditField   matlab.ui.control.NumericEditField
        facteurdelissageDfofEditFieldLabel  matlab.ui.control.Label
        valeurdvdtpourrecherchedebuttransitoireEditField  matlab.ui.control.NumericEditField
        valeurdvdtpourrecherchedebuttransitoireEditFieldLabel  matlab.ui.control.Label
        OuvrirScanButton                matlab.ui.control.Button
        limitesdanalyseCheckBox         matlab.ui.control.CheckBox
        SeuilAmplitudemaxEditField_2    matlab.ui.control.NumericEditField
        SeuilAmplitudemax01Label        matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        NomFichierEditField_2           matlab.ui.control.EditField
        NomFichierEditField_2Label      matlab.ui.control.Label
        CheminEditField_2               matlab.ui.control.EditField
        CheminEditField_2Label          matlab.ui.control.Label
        IntervalleTempsminimalEditField  matlab.ui.control.NumericEditField
        IntervalleTempsminimalEditFieldLabel  matlab.ui.control.Label
        dtsecEditField                  matlab.ui.control.NumericEditField
        dtsecEditFieldLabel             matlab.ui.control.Label
        SauvegardeButton                matlab.ui.control.Button
        CalculButton                    matlab.ui.control.Button
        ouvrirfichierButton             matlab.ui.control.Button
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes6_2                       matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Chemin % Description
        fichier
        flag_method
        flag_Seuil_plus_Intmin
        flag_limitetemps
        flag_reconst
        flag_run1rsttime
        curChar
        c_info

        Image_scan
        Profil_scan
        Profil_scan_base
        Baseline_new
        dfof
        dfof_smooth
        perc_seuil
        Inter_min
        time_scan
        temps_total
        dt
        temps_minimal
        amplitude_minimale
        valeur_Seuil_dvdt
        fen_lissage
        Transitoire
        Ttotalmin
        Freq_transitoire
        pre_transitoire
        post_transitoire
        No_Transitoire
        Temps_Max
        Amp_Max
        Tau_Decay
        CL
        time_max
        time_start
        Amp_max
        time_min
        Amp_min
        Half_Width
        dur90
        dvdt
        dVdTmax
        AUC
        BPM
        Resultats
        dir_result
        Nom_fichier_Res
        sse
        rsquare
        dfe
        adjrsquare
        rmse
        check_detrend
        %****************************************************


        %****************************************************
    end

    methods (Access = private)

        function RemoveZero(app,index)
            if app.flag_method==0
                app.No_Transitoire=app.No_Transitoire(index);
                app.Temps_Max=app.Temps_Max(index);
                app.Amp_Max=app.Amp_Max(index);
                app.Tau_Decay=app.Tau_Decay(index);
                app.CL=app.CL(index);
                app.Half_Width=app.Half_Width(index);
                app.AUC=app.AUC(index);
                app.sse=app.sse(index);
                app.rsquare=app.rsquare(index);
                app.dfe=app.dfe(index);
                app.adjrsquare=app.adjrsquare(index);
                app.rmse=app.rmse(index);
                app.BPM=app.BPM(index);
            else

                app.No_Transitoire= app.No_Transitoire(index);
                app.time_start= app.time_start(index);
                app.Tau_Decay=app.Tau_Decay(index);
                app.CL= app.CL(index);
                app.Half_Width=app.Half_Width(index);
                app.AUC=app.AUC(index);
                app.time_max=app.time_max(index);
                app.Amp_max=app.Amp_max(index);
                app.time_min= app.time_min(index);
                app.Amp_min=app.Amp_min(index);
                app.dur90=app.dur90(index);
                app.dVdTmax=app.dVdTmax(index);
                app.sse=app.sse(index);
                app.rsquare=app.rsquare(index);
                app.dfe=app.dfe(index);
                app.adjrsquare=app.adjrsquare(index);
                app.rmse=app.rmse(index);
                app.BPM=app.BPM(index);

            end


        end

        function Init_Table(app)
            app.No_Transitoire=zeros(5000,1);
            app.time_start=zeros(5000,1);
            app.Temps_Max=zeros(5000,1);
            app.Amp_Max=zeros(5000,1);
            app.Tau_Decay=zeros(5000,1);
            app.CL=zeros(5000,1);
            app.Half_Width=zeros(5000,1);
            app.AUC=zeros(5000,1);
            app.time_max=zeros(5000,1);
            app.Amp_max=zeros(5000,1);
            app.time_min=zeros(5000,1);
            app.Amp_min=zeros(5000,1);
            app.dur90=zeros(5000,1);
            app.dVdTmax=zeros(5000,1);
            app.sse=zeros(5000,1);
            app.rsquare=zeros(5000,1);
            app.dfe=zeros(5000,1);
            app.adjrsquare=zeros(5000,1);
            app.rmse=zeros(5000,1);
            app.BPM=zeros(5000,1);

            app.Resultats=table( app.No_Transitoire,app.time_start, app.time_max, app.Amp_max, app.time_min,app.Amp_min, app.Tau_Decay,...
                app.CL,app.Half_Width,app.dur90,app.dVdTmax,app.AUC,app.BPM,app.sse,app.rsquare,app.dfe,app.adjrsquare,app.rmse);

            app.UITable_2.Data=app.Resultats;

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.pre_transitoire=0.05;

            app.post_transitoire=0.1;
            app.preTransitoireEditField.Value=app.pre_transitoire;
            app.postTransitoireEditField.Value=app.post_transitoire;
            app.dt=0.00247;
            app.dtEditField.Value=app.dt;
            app.perc_seuil=0.4;
            app.SeuilAmplitudemaxEditField.Value=app.perc_seuil;

            app.IntervalleTempsminEditField.Value=0.1;
            app.IntervalleTempsminimalEditField.Value=5;

            app.limitesdanalyseCheckBox.Value=0;
            app.valeurdvdtpourrecherchedebuttransitoireEditField.Value=0;
            app.facteurdelissageDfofEditField.Value=0.02;
            app.reconstructiondelimageCheckBox.Value=0;
            app.flag_run1rsttime=0;
            app.TempsTotalEnregistrementsecEditField.Value=47.71;
        end

        % Button pushed function: ChargeScanButton
        function ChargeScanButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes2,"reset");
            cla(app.UIAxes3,"reset");
            cla(app.UIAxes4,"reset");
            cla(app.UIAxes5,"reset");

            if isempty(app.Chemin)
                app.Chemin = pwd;
                [app.fichier,app.Chemin]=uigetfile([app.Chemin,'\','*.*'],' file');
            else
                app.Chemin=app.CheminEditField.Value;
                [app.fichier,app.Chemin]=uigetfile([app.Chemin,'*.*'],' file');

            end

            if isequal([app.fichier,app.Chemin],[0,0])
                return
            else
                app.flag_method=0;
                app.CheminEditField.Value=app.Chemin;
                app.NomFichierEditField.Value=app.fichier;
                Nomfichier=[app.Chemin,app.fichier];
                app.Nom_fichier_Res=[strtok(app.fichier,'.'),'_Resultat.csv'];
                app.dir_result=[app.Chemin,'Resultat\'];
                flag_resultats=exist( app.dir_result,'dir');
                if flag_resultats==0
                    mkdir(app.dir_result);
                end
                %app.Image_scan=importdata(Nomfichier);
                app.Image_scan=imread(Nomfichier);
                app.Image_scan=im2gray(app.Image_scan);


                imagesc(app.UIAxes2,app.Image_scan)
                axis(app.UIAxes2,'image')


                time=(1:size(app.Image_scan,1)).*app.dt;
                app.Profil_scan=mean(app.Image_scan,2);
                valeur_fluo_min=mean(app.Profil_scan(1:5));
                if app.check_detrend==1


                    [imf,~,~]=emd(app.Profil_scan,'MaxNumIMF',10);

                    app.Profil_scan=sum(imf,2)+valeur_fluo_min;

                end


                plot(app.UIAxes4,time,app.Profil_scan)
                % imshow(app.Image_scan,'Parent',app.UIAxes2)
                %dt=0.002457646;


            end

        end

        % Button pushed function: CalculetSauvegardeButton
        function CalculetSauvegardeButtonPushed(app, event)
            app.No_Transitoire=zeros(5000,1);
            app.Temps_Max=zeros(5000,1);
            app.Amp_Max=zeros(5000,1);
            app.Tau_Decay=zeros(5000,1);
            app.CL=zeros(5000,1);
            app.Half_Width=zeros(5000,1);
            app.AUC=zeros(5000,1);
            app.sse=zeros(5000,1);
            app.rsquare=zeros(5000,1);
            app.dfe=zeros(5000,1);
            app.adjrsquare=zeros(5000,1);
            app.rmse=zeros(5000,1);
            app.BPM=zeros(5000,1);


            time=(1:size(app.Image_scan,1)).*app.dt;

            pre_It=round(app.pre_transitoire/app.dt);
            post_It=round(app.post_transitoire/app.dt);


            T_min=islocalmin(app.Profil_scan,'MinSeparation',0.2,'SamplePoints',time);

            timeImin=time(T_min);
            Meanbase=app.Profil_scan(T_min);
            Seuil_base=prctile(app.Profil_scan,5);
            Meanbase(1:end)=Seuil_base;

            app.Profil_scan_base=spline(timeImin,Meanbase,time)';
            app.dfof=(app.Profil_scan-app.Profil_scan_base)./app.Profil_scan_base;


            seuil=max(app.dfof)*app.perc_seuil;
            TF = islocalmax(app.dfof,'MinProminence',seuil,'MinSeparation',app.Inter_min,'SamplePoints',time);


            plot(app.UIAxes,time,app.dfof,time(TF),app.dfof(TF),'r*')

            app.Transitoire =time(TF);

            Transitoire_index=round((app.Transitoire/app.dt));
            Ttotal_min=time(end)/60;
            app.Freq_transitoire=length(app.Transitoire)/Ttotal_min;
            %
            % hold(app.UIAxes,"on")
            %
            Index_Transitoire_Conserve=logical(false(1,length(Transitoire_index)));

            Trans_ICal=zeros((pre_It+post_It+1),length(Transitoire_index));%cell(1,length(Transitoire_index));%;
            k=1;

            for i=1:length( Transitoire_index)

                if (app.Transitoire(i)-app.pre_transitoire)>=app.dt && (app.Transitoire(i)+app.post_transitoire)<=time(end)
                    Index_Transitoire_Conserve(i)=1;
                    app.No_Transitoire(i)=i;
                    app.Temps_Max(i)=time(Transitoire_index(i));
                    app.Amp_Max(i)=app.dfof(Transitoire_index(i));


                    Start=abs(pre_It-Transitoire_index(i));Stop=abs(post_It+Transitoire_index(i));

                    taille_T=length(app.dfof(Start:Stop));

                    Trans_ICal(1:taille_T,k)=app.dfof(Start:Stop);

                    x=[time(Start)  time(Start)  time(Stop) time(Stop)];
                    y=[0 max(app.dfof) max(app.dfof) 0 ];
                    patch(app.UIAxes,x,y,'r','FaceAlpha',0.3,'EdgeColor','r')
                    k=k+1;
                end
                % %
            end


            % if flag_dureed==0 && flag_dureef==0

            Transitoire_index=Transitoire_index( Index_Transitoire_Conserve);
            Trans_ICal= Trans_ICal(:,Index_Transitoire_Conserve);

            time_T=(1:size(Trans_ICal,1))*app.dt;
            plot(app.UIAxes3,time_T,Trans_ICal)
            app.CL(1:length(Transitoire_index))=[0 diff(app.Transitoire(Index_Transitoire_Conserve))];

            for i=1:length( Transitoire_index)
                Start=abs(Transitoire_index(i));Stop=abs(post_It+Transitoire_index(i));

                y=app.dfof(Start:Stop);
                x=((0:length(y)-1)*app.dt)';
                try

                    %A_max=max(y);

                    h_opt = fitoptions('Method','NonlinearLeastSquares',...
                        'MaxFunEvals',20000,'MaxIter',20000,...
                        'Tolfun',1e-9,'TolX',1e-9 );

                    [f ,g1]  = fit(x,y,'exp1', h_opt);
                    % coeffnames(f)
                    gof = struct2table(g1,RowNames="f");
                    app.sse(i)=gof.sse;
                    app. rsquare(i)=gof.rsquare;
                    app.dfe(i)=gof.dfe;
                    app.adjrsquare(i)=gof.adjrsquare;
                    app.rmse(i)=gof.rmse;
                    plot(app.UIAxes5,f,x,y)
                    pause(0.1)
                    app.Tau_Decay(i)=abs(1/f.b);

                catch

                    warning('echec du fit');
                    app.Tau_Decay(i)=0;
                    app.sse(i)=0;
                    app. rsquare(i)=0;
                    app.dfe(i)=0;
                    app.adjrsquare(i)=0;
                    app.rmse(i)=0;
                    plot(app.UIAxes5,f,x,y)
                end


                seuil_Half= app.dfof(Transitoire_index(i))*0.5;
                Index_half_deb=find(app.dfof(Start:Stop)>seuil_Half,1,"first")-1;
                IHd=Index_half_deb+Start;
                Index_Half_fin=find(app.dfof(Start:Stop)>seuil_Half,1,"last");
                IHf=Index_Half_fin+Start;

                app.Half_Width(i)=time(IHf)-time(IHd);
                app.AUC(i)=trapz( time_T,Trans_ICal(:,i));

            end
            app.BPM(1)=app.Freq_transitoire;
            Index_R=1:length( Transitoire_index);
            RemoveZero(app,Index_R);

            %**********************************************************
            %'No Transitoire'; 'Temps Max'; 'Amplitude Max'; 'Constante Temps DecroissanceCL'; 'Half-Width'; 'AUC; ...
            % BPM,sse;rsquare;dfe;adjrsquare;rmse '
            app.Resultats=table( app.No_Transitoire,app.Temps_Max,app.Amp_Max,app.Tau_Decay,...
                app.CL,app.Half_Width,app.AUC,app.BPM,app.sse,app.rsquare,app.dfe,app.adjrsquare,app.rmse);
            app.UITable.Data=app.Resultats;
            %msgbox(['frequence coups/min  :  ',num2str(app.Freq_transitoire)] )
            %********************************************************************************************************
            NomG=[app.dir_result,app.Nom_fichier_Res];
            app.Resultats.Properties.VariableNames=["No Transitoire", "Temps Max(sec)","'Amplitude Max",...
                "Tau Decroissance(sec)","CL(sec)", "Half-Width(sec)", "AUC","BPM","sse","rsquare","dfe","adjrsquare","rmse"];
            writetable( app.Resultats,NomG,'Delimiter','tab')
            %************************************************************************************************************

            %**********************************************************
            % else
            %     if flag_dureed==1
            %         msgbox('if faut reduire la duree du pre ' );
            %     end
            %      if flag_dureef==1
            %          msgbox('if faut reduire la fin du post ' );
            %      end
            %
            % end


        end

        % Value changed function: preTransitoireEditField
        function preTransitoireEditFieldValueChanged(app, event)
            app.pre_transitoire = app.preTransitoireEditField.Value;

        end

        % Value changed function: postTransitoireEditField
        function postTransitoireEditFieldValueChanged(app, event)
            app.post_transitoire = app.postTransitoireEditField.Value;

        end

        % Value changed function: SeuilAmplitudemaxEditField
        function SeuilAmplitudemaxEditFieldValueChanged(app, event)
            app.perc_seuil = app.SeuilAmplitudemaxEditField.Value;

        end

        % Value changed function: dtEditField
        function dtEditFieldValueChanged(app, event)
            app.dt = app.dtEditField.Value;

        end

        % Value changed function: IntervalleTempsminEditField
        function IntervalleTempsminEditFieldValueChanged(app, event)
            app.Inter_min = app.IntervalleTempsminEditField.Value;

        end

        % Value changed function: detrendCheckBox
        function detrendCheckBoxValueChanged(app, event)
            app.check_detrend = app.detrendCheckBox.Value;

        end

        % Button pushed function: ouvrirfichierButton
        function ouvrirfichierButtonPushed(app, event)
            app.Chemin = pwd;
            app.flag_run1rsttime=0;
             app.temps_total=app.TempsTotalEnregistrementsecEditField.Value;
            [app.fichier,app.Chemin]=uigetfile([app.Chemin,'\','*.*'],' file','');
            if isequal([app.fichier,app.Chemin],[0,0])
                return
            else
                app.flag_method=1;
                app.CheminEditField_2.Value=app.Chemin;
                app.NomFichierEditField_2.Value=app.fichier;
                app.Nom_fichier_Res=[strtok(app.fichier,'.'),'_Resultat.csv'];
                app.dir_result=[app.Chemin,'Resultat\'];
                flag_resultats=exist( app.dir_result,'dir');
                if flag_resultats==0
                    mkdir(app.dir_result);
                end

                data_scan=readtable([app.Chemin,app.fichier],'VariableNamingRule','preserve');
                app.time_scan=data_scan.(1);app.Profil_scan=data_scan.(2);
                if app.flag_limitetemps==1
                    figure('Name','Press Return when Done','KeyPressFcn',@(obj,evt) 0)
                    plot(app.time_scan,app.Profil_scan)
                    dcm_obj = datacursormode(gcf);
                    waitfor(gcf,'CurrentCharacter');
                    app.curChar=uint8(get(gcf,'CurrentCharacter'));
                    app.c_info = getCursorInfo(dcm_obj);
                    close(gcf)
                    temp=struct2table(app.c_info);

                    limtemp=sort(temp.DataIndex);
                    t_lim=app.time_scan(limtemp(1):limtemp(2));
                    cal_lim=app.Profil_scan(limtemp(1):limtemp(2));
                    app.time_scan=t_lim;app.Profil_scan=cal_lim;
                    plot(app.UIAxes6,  app.time_scan,app.Profil_scan)
                else
                    app.dtsecEditField.Value=mean(diff(app.time_scan));
                    plot(app.UIAxes6,  app.time_scan,app.Profil_scan)
                end

            end
        end

        % Button pushed function: OuvrirScanButton
        function OuvrirScanButtonPushed(app, event)
            cla(app.UIAxes9,"reset");
            cla(app.UIAxes8,"reset");
            cla(app.UIAxes7,"reset");
            cla(app.UIAxes6,"reset");
            cla(app.UIAxes6_2,"reset");
            app.flag_run1rsttime=0;
            Init_Table(app);
            app.flag_reconst=app.reconstructiondelimageCheckBox.Value;
            app.temps_total=app.TempsTotalEnregistrementsecEditField.Value;
            app.Chemin = pwd;
            if app.flag_reconst==0
                [app.fichier,app.Chemin]=uigetfile([app.Chemin,'\','*.*'],' file','');
                if isequal([app.fichier,app.Chemin],[0,0])
                    return
                else
                    app.flag_method=1;
                    app.CheminEditField_2.Value=app.Chemin;
                    app.NomFichierEditField_2.Value=app.fichier;
                    app.Nom_fichier_Res=[strtok(app.fichier,'.'),'_Resultat.csv'];
                    app.dir_result=[app.Chemin,'Resultat\'];
                    flag_resultats=exist( app.dir_result,'dir');
                    if flag_resultats==0
                        mkdir(app.dir_result);
                    end
                    Nomfichier=[app.Chemin,app.fichier];
                    app.Image_scan=imread(Nomfichier);
                    app.Image_scan=im2gray(app.Image_scan);

                    app.dt=app.temps_total/size(app.Image_scan,1);
                    app.dtsecEditField.Value=app.dt;
                    imagesc(app.UIAxes9,app.Image_scan)
                    axis(app.UIAxes9,'image')
                    app.time_scan=(1:size(app.Image_scan,1)).*app.dt;
                    app.time_scan=app.time_scan';
                    app.Profil_scan=mean(app.Image_scan,2);
                    plot(app.UIAxes6,app.time_scan,app.Profil_scan)

                end
            else
                app.flag_method=1;
                app.Image_scan=Stack_linescan([app.Chemin,'\']);
                app.CheminEditField_2.Value=[app.Chemin,'\'];
                imagesc(app.UIAxes9,app.Image_scan)
                axis(app.UIAxes9,'image')
                app.dt=app.temps_total/size(app.Image_scan,1);
                app.dtsecEditField.Value=app.dt;
                app.time_scan=(1:size(app.Image_scan,1)).*app.dt;
                app.time_scan=app.time_scan';
                app.Profil_scan=mean(app.Image_scan,2);
                plot(app.UIAxes6,app.time_scan,app.Profil_scan)

            end
            if app.flag_limitetemps==1
                figure('Name','Press Return when Done','KeyPressFcn',@(obj,evt) 0)
                plot(app.time_scan,app.Profil_scan)
                dcm_obj = datacursormode(gcf);
                waitfor(gcf,'CurrentCharacter');
                app.curChar=uint8(get(gcf,'CurrentCharacter'));
                app.c_info = getCursorInfo(dcm_obj);
                close(gcf)
                temp=struct2table(app.c_info);

                limtemp=sort(temp.DataIndex);
                t_lim=app.time_scan(limtemp(1):limtemp(2));
                cal_lim=app.Profil_scan(limtemp(1):limtemp(2));
                app.time_scan=t_lim;app.Profil_scan=cal_lim;
                plot(app.UIAxes6,  app.time_scan,app.Profil_scan)
            else
                app.dtsecEditField.Value=mean(diff(app.time_scan));
                plot(app.UIAxes6,  app.time_scan,app.Profil_scan)
            end
           app.IntervalleTempsminimalEditField.Value=5;
           DeterminationdelaFluoMinimaleBaseLineButtonPushed(app, event);
        end

        % Button pushed function: 
        % DeterminationdelaFluoMinimaleBaseLineButton
        function DeterminationdelaFluoMinimaleBaseLineButtonPushed(app, event)

            app.Inter_min=app.IntervalleTempsminimalEditField.Value;

            app.fen_lissage=app.facteurdelissageDfofEditField.Value;


            TH = islocalmin(app.Profil_scan,'MinSeparation',app.Inter_min,'SamplePoints',app.time_scan);

            Baseline=app.Profil_scan(TH);
            timeBaseline=app.time_scan(TH);
            app.Baseline_new= spline(timeBaseline,Baseline,app.time_scan);
            deviation=0.5;
            %*******************************************************************************************
            for j=1:length(app.Baseline_new)-1
                high_baseline= app.Baseline_new(j)+deviation;
                low_baseline=app.Baseline_new(j)-deviation;
                if (app.Baseline_new(j+1)>high_baseline || app.Baseline_new(j+1)<low_baseline)
                    app.Baseline_new(j+1)=app.Baseline_new(j);
                end
            end
            app.Baseline_new(j)=app.Baseline_new(j-1);
            %********************************************************************************************
            app. dfof= (app.Profil_scan-app.Baseline_new)./app.Baseline_new;
            app.dfof_smooth=smoothdata(app. dfof,'SmoothingFactor',app.fen_lissage);
            app.dvdt= [diff( app. dfof_smooth)./diff(app.time_scan);0];%

            plot(app.UIAxes6,  app.time_scan,app.Profil_scan)
            hold(app.UIAxes6,'on')
            plot(app.UIAxes6,app.time_scan(TH),app.Profil_scan(TH),'gd')
            plot(app.UIAxes6,app.time_scan,app.Baseline_new,'g')
            hold(app.UIAxes6,'off')
            ylabel(app.UIAxes6,  'UA')
            xlabel(app.UIAxes6,'time (sec)')

            plot(app.UIAxes6_2,  app.time_scan,app.dfof)
            hold(app.UIAxes6_2,'on')

            plot(app.UIAxes6_2,app.time_scan(TH),app.dfof(TH),'gd')
            plot(app.UIAxes6_2,  app.time_scan,    app.dfof_smooth,'r')
            hold(app.UIAxes6_2,"off")
            ylabel(app.UIAxes6_2,  'dfof')
            xlabel(app.UIAxes6_2,'time (sec)')

            plot(app.UIAxes8,  app.time_scan,  app.dvdt,'c')
            hold(app.UIAxes8,'on')
            plot(app.UIAxes8,  [app.time_scan(1) app.time_scan(end)] ,[0 0],'k')
            hold(app.UIAxes8,'off')
            ylabel(app.UIAxes8,  'dCa/dt')
            xlabel(app.UIAxes8,'time (sec)')
            linkaxes([app.UIAxes6 app.UIAxes6_2 app.UIAxes8],'x')
        end

        % Button pushed function: CalculButton
        function CalculButtonPushed(app, event)

            Init_Table(app);
            app.Inter_min = app.IntervalleTempsminimalEditField.Value;
            app.perc_seuil= app.SeuilAmplitudemaxEditField_2.Value;
            app.fen_lissage = app.facteurdelissageDfofEditField.Value;

            app.valeur_Seuil_dvdt = app.valeurdvdtpourrecherchedebuttransitoireEditField.Value;


            seuil=max(app.dfof)*app.perc_seuil;

            TF = islocalmax(app.dfof,'MinProminence', seuil,'MinSeparation',app.Inter_min,'SamplePoints',app.time_scan);

            TH = islocalmin(app.dfof,'MinProminence', seuil,'MinSeparation',app.Inter_min,'SamplePoints',app.time_scan);%
            TH(:,1)=0;

            indexmax=find(TF);


             seuil=max(app.dvdt)*app.perc_seuil;
            TMdvdt= islocalmax(app.dvdt,'MinProminence', seuil,'MinSeparation',app.Inter_min,'SamplePoints',app.time_scan);%
            TMdvdt(:)=0;

            for i=1:length(indexmax)-1
                if indexmax(i)<indexmax(i+1)
                    [~,Ind]=min(app.dfof(indexmax(i):indexmax(i+1)));
                    TH(indexmax(i)+Ind,1)=1;
                end
               [~,Inddvdt] =max(app.dvdt(indexmax(i):indexmax(i+1)));
                  TMdvdt(indexmax(i)+Inddvdt-1)=1;
            end
            [~,Ind]=min(app.dfof(indexmax(end):end));
            TH(indexmax(end)+Ind,1)=1;
             [~,Inddvdt] =max(app.dvdt(1:indexmax(1)));
            TMdvdt(Inddvdt)=1;
            indexmin=find(TH);


            indexmaxdvdt=find(TMdvdt);

            plot(app.UIAxes6,  app.time_scan,app.Profil_scan)
           
            hold(app.UIAxes6,'on')
            plot(app.UIAxes6,app.time_scan(TF),app.Profil_scan(TF),'r*','MarkerFaceColor','r')
            plot(app.UIAxes6,app.time_scan(TH),app.Profil_scan(TH),'gd','MarkerFaceColor','g')
            plot(app.UIAxes6,app.time_scan,app.Baseline_new,'g')
            hold(app.UIAxes6,'off')
            ylabel(app.UIAxes6,  'UA')
            xlabel(app.UIAxes6,'time (sec)')
            plot(app.UIAxes6_2,  app.time_scan,app.dfof)
            hold(app.UIAxes6_2,'on')
            plot(app.UIAxes6_2,app.time_scan(TF),app.dfof(TF),'r*','MarkerFaceColor','r')
            plot(app.UIAxes6_2,app.time_scan(TH),app.dfof(TH),'gd','MarkerFaceColor','g')
            plot(app.UIAxes6_2,  app.time_scan,    app.dfof_smooth,'r')

            hold(app.UIAxes6_2,"off")
            ylabel(app.UIAxes6_2,  'dfof')
            xlabel(app.UIAxes6_2,'time (sec)')
            plot(app.UIAxes8,  app.time_scan,  app.dvdt,'c')
            hold(app.UIAxes8,'on')
            plot(app.UIAxes8,  app.time_scan(indexmaxdvdt),  app.dvdt(indexmaxdvdt),'dk')
            plot(app.UIAxes8,  [app.time_scan(1) app.time_scan(end)] ,[0 0],'k')
            hold(app.UIAxes8,'off')
            ylabel(app.UIAxes8,  'dCa/dt')
            xlabel(app.UIAxes8,'time (sec)')

            linkaxes([app.UIAxes6 app.UIAxes6_2 app.UIAxes8],'x')

            try

                for i=1:length(indexmax)

                    app.No_Transitoire(i)=i;

                    app.time_max(i)=app.time_scan(indexmax(i));
                    app.Amp_max(i)=app.Profil_scan(indexmax(i));
                    app.time_min(i)=app.time_scan(indexmin(i));
                    app.Amp_min(i)=app.Profil_scan(indexmin(i));


                end
                %*********************************************************
                % recherche du debut transitoires avec les max del la dvdt
                %********************************************************
                index_start=zeros(1,length(indexmaxdvdt));

                for i=1:length( indexmaxdvdt)
                    app.dVdTmax(i)= app.dvdt( indexmaxdvdt(i));
                    for j= indexmaxdvdt(i):-1:1
                        if  app.dvdt( j)<app.valeur_Seuil_dvdt
                            index_start(i)=j;
                            app.time_start(i)=app.time_scan(j);
                            break;
                        end
                    end


                end
                %************************************************************
                % recherche des durées HalfWidth50% et durées 90% avec les
                % max transitoires calciques AUC avec Max debut,fin de
                % transitoires
                %************************************************************
                IHd50=zeros(1,length(indexmax));IHf50=zeros(1,length(indexmax)); IHf90=zeros(1,length(indexmax)) ;

                for i=1:length(indexmax)
                    seuil_Half= app.dfof(indexmax(i))*0.5;
                    %seuil_10= app.dfof(indexmax(i))*0.1;
                    Index_half_deb=find(app.dfof( index_start(i):indexmin(i))>seuil_Half,1,"first");
                    IHd50(i)=Index_half_deb+index_start(i);
                    Index_Half_fin=find(app.dfof(index_start(i):indexmin(i))>seuil_Half,1,"last");
                    IHf50(i)=Index_Half_fin+index_start(i);
                    app.Half_Width(i)=app.time_scan(IHf50(i))-app.time_scan(IHd50(i));
                    seuil_90= app.dfof(indexmax(i))*0.1;
                    %Index_half_deb=find(app.dfof(index_start(i):indexmin(i))>seuil_10,1,"first")-1;
                    %IHd=Index_half_deb+index_start(i);
                    Index_Half_fin=find(app.dfof(index_start(i):indexmin(i))>seuil_90,1,"last");
                    IHf90(i)=Index_Half_fin+index_start(i);
                    app.dur90(i)=app.time_scan(IHf90(i))-app.time_scan(index_start(i));

                    app.AUC(i)=trapz( app.time_scan(index_start(i):indexmin(i)),app.dfof(index_start(i):indexmin(i)));

                end

                hold(app.UIAxes6_2,'on')
                plot(app.UIAxes6_2,  app.time_scan(indexmax),app.dfof(indexmax),'rs','MarkerSize',10,'MarkerFaceColor','r')
                plot(app.UIAxes6_2,app.time_scan( index_start),app.dfof( index_start),'r>','MarkerSize',10,'MarkerFaceColor','r')
                plot(app.UIAxes6_2,app.time_scan(  IHd50),app.dfof(  IHd50),'gs','MarkerSize',10,'MarkerFaceColor','g')
                plot(app.UIAxes6_2,app.time_scan(  IHf50),app.dfof(  IHf50),'gs','MarkerSize',10,'MarkerFaceColor','g')
                plot(app.UIAxes6_2,app.time_scan(  IHf90),app.dfof(  IHf90),'ro','MarkerSize',10,'MarkerFaceColor','r')
                plot(app.UIAxes6_2,app.time_scan(indexmin),app.dfof(indexmin),'r<','MarkerSize',10,'MarkerFaceColor','r')
                plot(app.UIAxes6_2,  app.time_scan,    app.dfof_smooth,'r')

                hold(app.UIAxes6_2,'off')


                for i=1:length(indexmax)
                    y=app.dfof(indexmax(i):indexmin(i));
                    x=app.time_scan(indexmax(i):indexmin(i));
                    try

                        %A_max=max(y);

                        h_opt = fitoptions('Method','NonlinearLeastSquares',...
                            'MaxFunEvals',20000,'MaxIter',20000,...
                            'Tolfun',1e-9,'TolX',1e-9 );

                        [f ,g1]  = fit(x,y,'exp1', h_opt);
                        % coeffnames(f)
                        gof = struct2table(g1,RowNames="f");
                        app.sse(i)=gof.sse;
                        app. rsquare(i)=gof.rsquare;
                        app.dfe(i)=gof.dfe;
                        app.adjrsquare(i)=gof.adjrsquare;
                        app.rmse(i)=gof.rmse;
                        plot(app.UIAxes7,f,x,y)
                        ylabel(app.UIAxes7,  'dfof')
                        xlabel(app.UIAxes7,'time (sec)')
                        pause(0.1)
                        app.Tau_Decay(i)=abs(1/f.b);

                    catch

                        warning('echec du fit');
                        app.Tau_Decay(i)=0;
                        app.sse(i)=0;
                        app. rsquare(i)=0;
                        app.dfe(i)=0;
                        app.adjrsquare(i)=0;
                        app.rmse(i)=0;
                        plot(app.UIAxes7,f,x,y)
                        ylabel(app.UIAxes7,  'dfof')
                        xlabel(app.UIAxes7,'time (sec)')
                    end
                    
                end
               
                app.CL(1:length(indexmax))=[0 ;diff(app.time_scan(indexmax))];


                Ttotal_min=app.time_scan(end)/60;
                app.Freq_transitoire=length(indexmax)/Ttotal_min;
                app.BPM(1)=   app.Freq_transitoire;

                Index_R=1:length(indexmax);
                RemoveZero(app,Index_R);

                app.Resultats=table( app.No_Transitoire,app.time_start, app.time_max, app.Amp_max, app.time_min,app.Amp_min, app.Tau_Decay,...
                    app.CL,app.Half_Width,app.dur90,app.dVdTmax,app.AUC,app.BPM,app.sse,app.rsquare,app.dfe,app.adjrsquare,app.rmse);

                app.UITable_2.Data=app.Resultats;
                
            catch

                errordlg('probleme de detection');
                warning('probleme de detection');
            end


        end

        % Button pushed function: SauvegardeButton
        function SauvegardeButtonPushed(app, event)

            NomG=[app.dir_result,app.Nom_fichier_Res];
            app.Resultats.Properties.VariableNames=["No Transitoire","Temps debut(sec)","Temps Max(sec)","Amplitude Max",...
                "Temps Min(sec)","Amplitude Min","Tau Decroissance(sec)","CL(sec)","Half-Width(sec)","Duration90(sec)",...
                "dV/dt max","AUC","BPM","sse","rsquare","dfe","adjrsquare","rmse"];
            writetable( app.Resultats,NomG,'Delimiter','tab')
        end

        % Value changed function: IntervalleTempsminimalEditField
        function IntervalleTempsminimalEditFieldValueChanged(app, event)
            app.Inter_min = app.IntervalleTempsminimalEditField.Value;

        end

        % Value changed function: SeuilAmplitudemaxEditField_2
        function SeuilAmplitudemaxEditField_2ValueChanged(app, event)
            app.perc_seuil = app.SeuilAmplitudemaxEditField_2.Value;


            seuil=max(app.dvdt)*app.perc_seuil;
            plot(app.UIAxes8,  app.time_scan,app.dvdt,'c')
            hold(app.UIAxes8,'on')
            plot(app.UIAxes8,  [app.time_scan(1) app.time_scan(end)] ,[seuil seuil],'k')
            hold(app.UIAxes8,'off')
             ylabel(app.UIAxes8,  'dCa/dt')
            xlabel(app.UIAxes8,'time (sec)')
            seuil1=max(app.dfof)*app.perc_seuil;
            plot(app.UIAxes6_2,  app.time_scan,app.dfof,'c')
            hold(app.UIAxes6_2,'on')
            plot(app.UIAxes6_2,  [app.time_scan(1) app.time_scan(end)] ,[seuil1 seuil1],'k')
            hold(app.UIAxes6_2,'off')
            ylabel(app.UIAxes6_2,  'UA')
            xlabel(app.UIAxes6_2,'time (sec)')


        end

        % Value changed function: 
        % valeurdvdtpourrecherchedebuttransitoireEditField
        function valeurdvdtpourrecherchedebuttransitoireEditFieldValueChanged(app, event)
            app.valeur_Seuil_dvdt = app.valeurdvdtpourrecherchedebuttransitoireEditField.Value;

        end

        % Value changed function: facteurdelissageDfofEditField
        function facteurdelissageDfofEditFieldValueChanged(app, event)
            app.fen_lissage = app.facteurdelissageDfofEditField.Value;

        end

        % Value changed function: limitesdanalyseCheckBox
        function limitesdanalyseCheckBoxValueChanged(app, event)
            app.flag_limitetemps = app.limitesdanalyseCheckBox.Value;

        end

        % Value changed function: reconstructiondelimageCheckBox
        function reconstructiondelimageCheckBoxValueChanged(app, event)
            app.flag_reconst = app.reconstructiondelimageCheckBox.Value;

        end

        % Value changed function: TempsTotalEnregistrementsecEditField
        function TempsTotalEnregistrementsecEditFieldValueChanged(app, event)
            app.temps_total = app.TempsTotalEnregistrementsecEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnalyseImageScanTab = uitab(app.TabGroup);
            app.AnalyseImageScanTab.Title = 'Analyse Image Scan';
            app.UIAxes2 = uiaxes(app.AnalyseImageScanTab);
            title(app.UIAxes2, 'Calcium Scan')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes4 = uiaxes(app.AnalyseImageScanTab);
            title(app.UIAxes4, 'Profil Calcium')
            xlabel(app.UIAxes4, 'time (sec)')
            ylabel(app.UIAxes4, 'mean')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes = uiaxes(app.AnalyseImageScanTab);
            title(app.UIAxes, 'Profil Calcium')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'meanDofof( AU)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes5 = uiaxes(app.AnalyseImageScanTab);
            title(app.UIAxes5, 'fit')
            xlabel(app.UIAxes5, 'time(sec)')
            ylabel(app.UIAxes5, 'AU')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes3 = uiaxes(app.AnalyseImageScanTab);
            title(app.UIAxes3, 'Transitoire Calcium')
            xlabel(app.UIAxes3, 'time (sec)')
            ylabel(app.UIAxes3, 'AU')
            zlabel(app.UIAxes3, 'Z')
            app.CheminEditFieldLabel = uilabel(app.AnalyseImageScanTab);
            app.CheminEditFieldLabel.Text = 'Chemin';
            app.CheminEditField = uieditfield(app.AnalyseImageScanTab, 'text');
            app.NomFichierEditFieldLabel = uilabel(app.AnalyseImageScanTab);
            app.NomFichierEditFieldLabel.Text = 'Nom Fichier';
            app.NomFichierEditField = uieditfield(app.AnalyseImageScanTab, 'text');
            app.ChargeScanButton = uibutton(app.AnalyseImageScanTab, 'push');
            app.ChargeScanButton.ButtonPushedFcn = createCallbackFcn(app, @ChargeScanButtonPushed, true);
            app.ChargeScanButton.Text = 'Charge Scan';
            app.CalculetSauvegardeButton = uibutton(app.AnalyseImageScanTab, 'push');
            app.CalculetSauvegardeButton.ButtonPushedFcn = createCallbackFcn(app, @CalculetSauvegardeButtonPushed, true);
            app.CalculetSauvegardeButton.Text = 'Calcul et Sauvegarde';
            app.detrendCheckBox = uicheckbox(app.AnalyseImageScanTab);
            app.detrendCheckBox.ValueChangedFcn = createCallbackFcn(app, @detrendCheckBoxValueChanged, true);
            app.detrendCheckBox.Text = 'detrend';
            app.preTransitoireEditFieldLabel = uilabel(app.AnalyseImageScanTab);
            app.preTransitoireEditFieldLabel.Text = 'pre  Transitoire';
            app.preTransitoireEditField = uieditfield(app.AnalyseImageScanTab, 'numeric');
            app.preTransitoireEditField.Limits = [0.0001 100];
            app.preTransitoireEditField.ValueChangedFcn = createCallbackFcn(app, @preTransitoireEditFieldValueChanged, true);
            app.preTransitoireEditField.Value = 3;
            app.postTransitoireEditFieldLabel = uilabel(app.AnalyseImageScanTab);
            app.postTransitoireEditFieldLabel.Text = 'post  Transitoire';
            app.postTransitoireEditField = uieditfield(app.AnalyseImageScanTab, 'numeric');
            app.postTransitoireEditField.Limits = [0.0001 100];
            app.postTransitoireEditField.ValueChangedFcn = createCallbackFcn(app, @postTransitoireEditFieldValueChanged, true);
            app.postTransitoireEditField.Value = 3;
            app.dtEditFieldLabel = uilabel(app.AnalyseImageScanTab);
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEditField = uieditfield(app.AnalyseImageScanTab, 'numeric');
            app.dtEditField.Limits = [0 100];
            app.dtEditField.ValueChangedFcn = createCallbackFcn(app, @dtEditFieldValueChanged, true);
            app.dtEditField.Value = 0.002457646;
            app.SeuilAmplitudemaxEditFieldLabel = uilabel(app.AnalyseImageScanTab);
            app.SeuilAmplitudemaxEditFieldLabel.Text = 'Seuil  %Amplitude max';
            app.SeuilAmplitudemaxEditField = uieditfield(app.AnalyseImageScanTab, 'numeric');
            app.SeuilAmplitudemaxEditField.ValueChangedFcn = createCallbackFcn(app, @SeuilAmplitudemaxEditFieldValueChanged, true);
            app.SeuilAmplitudemaxEditField.Value = 0.4;
            app.IntervalleTempsminEditFieldLabel = uilabel(app.AnalyseImageScanTab);
            app.IntervalleTempsminEditFieldLabel.Text = 'Intervalle Temps min';
            app.IntervalleTempsminEditField = uieditfield(app.AnalyseImageScanTab, 'numeric');
            app.IntervalleTempsminEditField.ValueChangedFcn = createCallbackFcn(app, @IntervalleTempsminEditFieldValueChanged, true);
            app.IntervalleTempsminEditField.Value = 0.1;
            app.UITable = uitable(app.AnalyseImageScanTab);
            app.UITable.ColumnName = {'No Transitoire'; 'Temps Max(sec)'; 'Amplitude Max'; 'Tau Decroissance(sec)'; 'CL(sec)'; 'Half-Width(sec)'; 'AUC'; 'BPM'; 'sse'; 'rsquare'; 'dfe'; 'adjrsquare'; 'rmse'};
            app.UITable.RowName = {};
            app.AnalysefichierscanTab = uitab(app.TabGroup);
            app.AnalysefichierscanTab.Title = 'Analyse fichier scan';
            app.UIAxes6 = uiaxes(app.AnalysefichierscanTab);
            title(app.UIAxes6, 'Profil Calcium')
            xlabel(app.UIAxes6, 'time (msec)')
            ylabel(app.UIAxes6, 'AU')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6_2 = uiaxes(app.AnalysefichierscanTab);
            title(app.UIAxes6_2, 'Profil Calcium')
            xlabel(app.UIAxes6_2, 'time (msec)')
            ylabel(app.UIAxes6_2, 'DfoF')
            zlabel(app.UIAxes6_2, 'Z')
            app.UIAxes7 = uiaxes(app.AnalysefichierscanTab);
            title(app.UIAxes7, 'fit')
            xlabel(app.UIAxes7, 'time (msec)')
            ylabel(app.UIAxes7, 'DfoF')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.AnalysefichierscanTab);
            title(app.UIAxes8, 'derive')
            xlabel(app.UIAxes8, 'time (msec)')
            ylabel(app.UIAxes8, 'dCadt')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.AnalysefichierscanTab);
            title(app.UIAxes9, 'Calcium Scan')
            app.UIAxes9.XTick = [];
            app.UIAxes9.YTick = [];
            app.ouvrirfichierButton = uibutton(app.AnalysefichierscanTab, 'push');
            app.ouvrirfichierButton.ButtonPushedFcn = createCallbackFcn(app, @ouvrirfichierButtonPushed, true);
            app.ouvrirfichierButton.Text = 'ouvrir fichier';
            app.CalculButton = uibutton(app.AnalysefichierscanTab, 'push');
            app.CalculButton.ButtonPushedFcn = createCallbackFcn(app, @CalculButtonPushed, true);
            app.CalculButton.Text = 'Calcul ';
            app.SauvegardeButton = uibutton(app.AnalysefichierscanTab, 'push');
            app.SauvegardeButton.ButtonPushedFcn = createCallbackFcn(app, @SauvegardeButtonPushed, true);
            app.SauvegardeButton.Text = 'Sauvegarde';
            app.dtsecEditFieldLabel = uilabel(app.AnalysefichierscanTab);
            app.dtsecEditFieldLabel.Text = 'dt (sec)';
            app.dtsecEditField = uieditfield(app.AnalysefichierscanTab, 'numeric');
            app.dtsecEditField.Limits = [0 100];
            app.dtsecEditField.Value = 0.00247;
            app.IntervalleTempsminimalEditFieldLabel = uilabel(app.AnalysefichierscanTab);
            app.IntervalleTempsminimalEditFieldLabel.Text = 'Intervalle Temps minimal';
            app.IntervalleTempsminimalEditField = uieditfield(app.AnalysefichierscanTab, 'numeric');
            app.IntervalleTempsminimalEditField.ValueChangedFcn = createCallbackFcn(app, @IntervalleTempsminimalEditFieldValueChanged, true);
            app.IntervalleTempsminimalEditField.Value = 30;
            app.CheminEditField_2Label = uilabel(app.AnalysefichierscanTab);
            app.CheminEditField_2Label.Text = 'Chemin';
            app.CheminEditField_2 = uieditfield(app.AnalysefichierscanTab, 'text');
            app.NomFichierEditField_2Label = uilabel(app.AnalysefichierscanTab);
            app.NomFichierEditField_2Label.Text = 'Nom Fichier';
            app.NomFichierEditField_2 = uieditfield(app.AnalysefichierscanTab, 'text');
            app.UITable_2 = uitable(app.AnalysefichierscanTab);
            app.UITable_2.ColumnName = {'No Transitoire'; 'Temps debut(msec)'; 'Temps Max(sec)'; 'Amplitude Max'; 'Temps Min(sec)'; 'Amplitude Min'; 'Tau Decroissance(sec)'; 'CL(sec)'; 'Half-Width(sec)'; 'Duration90(sec)'; 'dV/dt max'; 'AUC'; 'BPM'; 'sse'; 'rsquare'; 'dfe'; 'adjrsquare'; 'rmse'};
            app.UITable_2.RowName = {};
            app.SeuilAmplitudemax01Label = uilabel(app.AnalysefichierscanTab);
            app.SeuilAmplitudemax01Label.Text = 'Seuil  %Amplitude max';
            app.SeuilAmplitudemaxEditField_2 = uieditfield(app.AnalysefichierscanTab, 'numeric');
            app.SeuilAmplitudemaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @SeuilAmplitudemaxEditField_2ValueChanged, true);
            app.SeuilAmplitudemaxEditField_2.Value = 0.4;
            app.limitesdanalyseCheckBox = uicheckbox(app.AnalysefichierscanTab);
            app.limitesdanalyseCheckBox.ValueChangedFcn = createCallbackFcn(app, @limitesdanalyseCheckBoxValueChanged, true);
            app.limitesdanalyseCheckBox.Text = 'limites d'' analyse';
            app.OuvrirScanButton = uibutton(app.AnalysefichierscanTab, 'push');
            app.OuvrirScanButton.ButtonPushedFcn = createCallbackFcn(app, @OuvrirScanButtonPushed, true);
            app.OuvrirScanButton.Text = 'Ouvrir Scan';
            app.valeurdvdtpourrecherchedebuttransitoireEditFieldLabel = uilabel(app.AnalysefichierscanTab);
            app.valeurdvdtpourrecherchedebuttransitoireEditFieldLabel.Text = 'valeur  dvdt pour  recherche debut transitoire';
            app.valeurdvdtpourrecherchedebuttransitoireEditField = uieditfield(app.AnalysefichierscanTab, 'numeric');
            app.valeurdvdtpourrecherchedebuttransitoireEditField.Limits = [0 20];
            app.valeurdvdtpourrecherchedebuttransitoireEditField.ValueChangedFcn = createCallbackFcn(app, @valeurdvdtpourrecherchedebuttransitoireEditFieldValueChanged, true);
            app.valeurdvdtpourrecherchedebuttransitoireEditField.Value = 0.001;
            app.facteurdelissageDfofEditFieldLabel = uilabel(app.AnalysefichierscanTab);
            app.facteurdelissageDfofEditFieldLabel.Text = 'facteur de lissage Dfof';
            app.facteurdelissageDfofEditField = uieditfield(app.AnalysefichierscanTab, 'numeric');
            app.facteurdelissageDfofEditField.ValueChangedFcn = createCallbackFcn(app, @facteurdelissageDfofEditFieldValueChanged, true);
            app.facteurdelissageDfofEditField.Value = 0.002;
            app.reconstructiondelimageCheckBox = uicheckbox(app.AnalysefichierscanTab);
            app.reconstructiondelimageCheckBox.ValueChangedFcn = createCallbackFcn(app, @reconstructiondelimageCheckBoxValueChanged, true);
            app.reconstructiondelimageCheckBox.Text = 'reconstruction de l''image';
            app.DeterminationdelaFluoMinimaleBaseLineButton = uibutton(app.AnalysefichierscanTab, 'push');
            app.DeterminationdelaFluoMinimaleBaseLineButton.ButtonPushedFcn = createCallbackFcn(app, @DeterminationdelaFluoMinimaleBaseLineButtonPushed, true);
            app.DeterminationdelaFluoMinimaleBaseLineButton.Text = 'Determination de la Fluo Minimale(Base Line)';
            app.TempsTotalEnregistrementsecEditFieldLabel = uilabel(app.AnalysefichierscanTab);
            app.TempsTotalEnregistrementsecEditFieldLabel.Text = 'Temps Total Enregistrement (sec)';
            app.TempsTotalEnregistrementsecEditField = uieditfield(app.AnalysefichierscanTab, 'numeric');
            app.TempsTotalEnregistrementsecEditField.ValueChangedFcn = createCallbackFcn(app, @TempsTotalEnregistrementsecEditFieldValueChanged, true);
            app.TempsTotalEnregistrementsecEditField.Value = 47.71;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analyse_CalciumScan_Transitoire

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end